classdef ML_APITester < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu_Function               matlab.ui.container.Menu
        Menu_ClearHistory           matlab.ui.container.Menu
        Menu_Help                   matlab.ui.container.Menu
        Menu_Version                matlab.ui.container.Menu
        GridLayout_Main             matlab.ui.container.GridLayout
        Label_AppStatusMessage      matlab.ui.control.Label
        APICallHistoryLabel         matlab.ui.control.Label
        ListBox_History             matlab.ui.control.ListBox
        GridLayout_Top              matlab.ui.container.GridLayout
        DropDown_Method             matlab.ui.control.DropDown
        EditField_URI               matlab.ui.control.EditField
        Image_Send                  matlab.ui.control.Image
        TabGroup_Request            matlab.ui.container.TabGroup
        ParamsTab                   matlab.ui.container.Tab
        GridLayout_Params           matlab.ui.container.GridLayout
        GridLayout_ParamsBT         matlab.ui.container.GridLayout
        Image_ParamsAdd             matlab.ui.control.Image
        Image_ParamsDel             matlab.ui.control.Image
        UITable_Params              matlab.ui.control.Table
        HeadersTab                  matlab.ui.container.Tab
        GridLayout_Headers          matlab.ui.container.GridLayout
        GridLayout_HeadersBT        matlab.ui.container.GridLayout
        Image_HeadersAdd            matlab.ui.control.Image
        Image_HeadersDel            matlab.ui.control.Image
        Image_HeaderHide            matlab.ui.control.Image
        Image_HeaderShow            matlab.ui.control.Image
        UITable_Headers             matlab.ui.control.Table
        BodyTab                     matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        ButtonGroup_Body            matlab.ui.container.ButtonGroup
        NoneButton                  matlab.ui.control.RadioButton
        JSONButton                  matlab.ui.control.RadioButton
        TextButton                  matlab.ui.control.RadioButton
        TextArea_Body               matlab.ui.control.TextArea
        GridLayout_Response         matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        ResponseBodyTab             matlab.ui.container.Tab
        GridLayout_Response1        matlab.ui.container.GridLayout
        TextArea_ResponseBody       matlab.ui.control.TextArea
        ResponseHeadersTab          matlab.ui.container.Tab
        GridLayout_Response2        matlab.ui.container.GridLayout
        UITable_ResponseHeaders     matlab.ui.control.Table
        DebugLogTab                 matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        TextArea_DebugLog           matlab.ui.control.TextArea
        GridLayout_ResponseButtons  matlab.ui.container.GridLayout
        Image_SaveToVar             matlab.ui.control.Image
        Image_SaveToMfile           matlab.ui.control.Image
        ContextMenu                 matlab.ui.container.ContextMenu
        DeleteHistory               matlab.ui.container.Menu
    end

    properties (Access = public)
        AppName = "API Tester for MATLAB";
        VersionNo = "1.0";
        appControllerObj;
        apiCallHistoryModelObj;
        appStatusMessageModelObj;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize models
            app.apiCallHistoryModelObj = common.model.APICallHistoryModel();
            app.appStatusMessageModelObj = common.model.AppStatusMessageModel();
            
            % Initialize controller
            app.appControllerObj = common.controller.AppController(app);

            % Call startup function
            app.appControllerObj.startup(app);
        end

        % Callback function: ButtonGroup_Body, DeleteHistory,
        % EditField_URI, 
        % ...and 15 other components
        function onCallback(app, event)
            % Send to Callback Handler
            app.appControllerObj.handleCallback(app, event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu_Function = uimenu(app.UIFigure);
            app.Menu_Function.Text = 'Function   ';
            app.Menu_ClearHistory = uimenu(app.Menu_Function);
            app.Menu_ClearHistory.MenuSelectedFcn = createCallbackFcn(app, @onCallback, true);
            app.Menu_ClearHistory.Text = 'Clear API Call History';
            app.Menu_ClearHistory.Tag = 'ClearAPICallHistory';
            app.Menu_Help = uimenu(app.UIFigure);
            app.Menu_Help.Text = 'Help   ';
            app.Menu_Version = uimenu(app.Menu_Help);
            app.Menu_Version.MenuSelectedFcn = createCallbackFcn(app, @onCallback, true);
            app.Menu_Version.Text = 'Version';
            app.Menu_Version.Tag = 'ShowAppVersion';
            app.GridLayout_Main = uigridlayout(app.UIFigure);
            app.GridLayout_Response = uigridlayout(app.GridLayout_Main);
            app.GridLayout_ResponseButtons = uigridlayout(app.GridLayout_Response);
            app.Image_SaveToMfile = uiimage(app.GridLayout_ResponseButtons);
            app.Image_SaveToMfile.ImageClickedFcn = createCallbackFcn(app, @onCallback, true);
            app.Image_SaveToMfile.Tag = 'SaveToMfile';
            app.Image_SaveToMfile.Tooltip = {'Generate MATLAB code'};
            app.Image_SaveToMfile.ImageSource = fullfile(pathToMLAPP, 'images', 'saveToMfile.png');
            app.Image_SaveToVar = uiimage(app.GridLayout_ResponseButtons);
            app.Image_SaveToVar.ImageClickedFcn = createCallbackFcn(app, @onCallback, true);
            app.Image_SaveToVar.Tag = 'SaveToVar';
            app.Image_SaveToVar.Tooltip = {'Save Response Body to MATLAB variable'};
            app.Image_SaveToVar.ImageSource = fullfile(pathToMLAPP, 'images', 'saveToVar.png');
            app.TabGroup = uitabgroup(app.GridLayout_Response);
            app.ResponseBodyTab = uitab(app.TabGroup);
            app.ResponseBodyTab.Title = 'Body';
            app.GridLayout_Response1 = uigridlayout(app.ResponseBodyTab);
            app.TextArea_ResponseBody = uitextarea(app.GridLayout_Response1);
            app.TextArea_ResponseBody.Placeholder = 'Response Body';
            app.ResponseHeadersTab = uitab(app.TabGroup);
            app.ResponseHeadersTab.Title = 'Headers';
            app.GridLayout_Response2 = uigridlayout(app.ResponseHeadersTab);
            app.UITable_ResponseHeaders = uitable(app.GridLayout_Response2);
            app.UITable_ResponseHeaders.ColumnName = {'Key'; 'Value'};
            app.UITable_ResponseHeaders.RowName = {};
            app.DebugLogTab = uitab(app.TabGroup);
            app.DebugLogTab.Title = 'Debug Log';
            app.GridLayout2 = uigridlayout(app.DebugLogTab);
            app.TextArea_DebugLog = uitextarea(app.GridLayout2);
            app.TextArea_DebugLog.Placeholder = 'Debug Log';
            app.TabGroup_Request = uitabgroup(app.GridLayout_Main);
            app.ParamsTab = uitab(app.TabGroup_Request);
            app.ParamsTab.Title = 'Params';
            app.GridLayout_Params = uigridlayout(app.ParamsTab);
            app.UITable_Params = uitable(app.GridLayout_Params);
            app.UITable_Params.ColumnName = {'#'; 'Key'; 'Value'};
            app.UITable_Params.RowName = {};
            app.UITable_Params.ColumnEditable = true;
            app.UITable_Params.CellEditCallback = createCallbackFcn(app, @onCallback, true);
            app.UITable_Params.Tag = 'ParamsTable';
            app.GridLayout_ParamsBT = uigridlayout(app.GridLayout_Params);
            app.Image_ParamsDel = uiimage(app.GridLayout_ParamsBT);
            app.Image_ParamsDel.ImageClickedFcn = createCallbackFcn(app, @onCallback, true);
            app.Image_ParamsDel.Tag = 'DeleteRow';
            app.Image_ParamsDel.ImageSource = fullfile(pathToMLAPP, 'images', 'minus.png');
            app.Image_ParamsAdd = uiimage(app.GridLayout_ParamsBT);
            app.Image_ParamsAdd.ImageClickedFcn = createCallbackFcn(app, @onCallback, true);
            app.Image_ParamsAdd.Tag = 'AddRow';
            app.Image_ParamsAdd.ImageSource = fullfile(pathToMLAPP, 'images', 'plus.png');
            app.HeadersTab = uitab(app.TabGroup_Request);
            app.HeadersTab.Title = 'Headers';
            app.GridLayout_Headers = uigridlayout(app.HeadersTab);
            app.UITable_Headers = uitable(app.GridLayout_Headers);
            app.UITable_Headers.ColumnName = {'#'; 'Key'; 'Value'};
            app.UITable_Headers.RowName = {};
            app.UITable_Headers.ColumnEditable = true;
            app.UITable_Headers.CellEditCallback = createCallbackFcn(app, @onCallback, true);
            app.UITable_Headers.Tag = 'HeadersTable';
            app.GridLayout_HeadersBT = uigridlayout(app.GridLayout_Headers);
            app.Image_HeaderShow = uiimage(app.GridLayout_HeadersBT);
            app.Image_HeaderShow.ImageClickedFcn = createCallbackFcn(app, @onCallback, true);
            app.Image_HeaderShow.Tag = 'ShowHeader';
            app.Image_HeaderShow.Tooltip = {'Show value'};
            app.Image_HeaderShow.ImageSource = fullfile(pathToMLAPP, 'images', 'show.png');
            app.Image_HeaderHide = uiimage(app.GridLayout_HeadersBT);
            app.Image_HeaderHide.ImageClickedFcn = createCallbackFcn(app, @onCallback, true);
            app.Image_HeaderHide.Tag = 'HideHeader';
            app.Image_HeaderHide.Tooltip = {'Hide value'};
            app.Image_HeaderHide.ImageSource = fullfile(pathToMLAPP, 'images', 'hide.png');
            app.Image_HeadersDel = uiimage(app.GridLayout_HeadersBT);
            app.Image_HeadersDel.ImageClickedFcn = createCallbackFcn(app, @onCallback, true);
            app.Image_HeadersDel.Tag = 'DeleteRow';
            app.Image_HeadersDel.ImageSource = fullfile(pathToMLAPP, 'images', 'minus.png');
            app.Image_HeadersAdd = uiimage(app.GridLayout_HeadersBT);
            app.Image_HeadersAdd.ImageClickedFcn = createCallbackFcn(app, @onCallback, true);
            app.Image_HeadersAdd.Tag = 'AddRow';
            app.Image_HeadersAdd.ImageSource = fullfile(pathToMLAPP, 'images', 'plus.png');
            app.BodyTab = uitab(app.TabGroup_Request);
            app.BodyTab.Title = 'Body';
            app.GridLayout = uigridlayout(app.BodyTab);
            app.TextArea_Body = uitextarea(app.GridLayout);
            app.TextArea_Body.Placeholder = 'No Request Body';
            app.ButtonGroup_Body = uibuttongroup(app.GridLayout);
            app.ButtonGroup_Body.SelectionChangedFcn = createCallbackFcn(app, @onCallback, true);
            app.ButtonGroup_Body.Tag = 'BodyContentType';
            app.TextButton = uiradiobutton(app.ButtonGroup_Body);
            app.TextButton.Text = 'Text';
            app.JSONButton = uiradiobutton(app.ButtonGroup_Body);
            app.JSONButton.Text = 'JSON';
            app.NoneButton = uiradiobutton(app.ButtonGroup_Body);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.GridLayout_Top = uigridlayout(app.GridLayout_Main);
            app.Image_Send = uiimage(app.GridLayout_Top);
            app.Image_Send.ImageClickedFcn = createCallbackFcn(app, @onCallback, true);
            app.Image_Send.Tag = 'SendAPIRequest';
            app.Image_Send.Tooltip = {'Send a request'};
            app.Image_Send.ImageSource = fullfile(pathToMLAPP, 'images', 'play.png');
            app.EditField_URI = uieditfield(app.GridLayout_Top, 'text');
            app.EditField_URI.ValueChangedFcn = createCallbackFcn(app, @onCallback, true);
            app.EditField_URI.Tag = 'URIValue';
            app.EditField_URI.Placeholder = 'URI';
            app.DropDown_Method = uidropdown(app.GridLayout_Top);
            app.DropDown_Method.Items = {'GET', 'POST'};
            app.DropDown_Method.Value = 'GET';
            app.ListBox_History = uilistbox(app.GridLayout_Main);
            app.ListBox_History.Items = {};
            app.ListBox_History.Tag = 'APICallHistoryList';
            app.ListBox_History.Tooltip = {'Call History'};
            app.ListBox_History.ClickedFcn = createCallbackFcn(app, @onCallback, true);
            app.ListBox_History.DoubleClickedFcn = createCallbackFcn(app, @onCallback, true);
            app.ListBox_History.Value = {};
            app.APICallHistoryLabel = uilabel(app.GridLayout_Main);
            app.APICallHistoryLabel.Text = 'API Call History';
            app.Label_AppStatusMessage = uilabel(app.GridLayout_Main);
            app.Label_AppStatusMessage.Tooltip = {'API Call Result'};
            app.Label_AppStatusMessage.Text = '';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.DeleteHistory = uimenu(app.ContextMenu);
            app.DeleteHistory.MenuSelectedFcn = createCallbackFcn(app, @onCallback, true);
            app.DeleteHistory.Text = 'Delete';
            app.DeleteHistory.Tag = 'DeleteApiCallHistoryList';
            % Assign app.ContextMenu
            app.ListBox_History.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ML_APITester

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end